classdef models < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PropagationmodelsUIFigure   matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        CalculateButton             matlab.ui.control.Button
        AntennaheightmSliderLabel   matlab.ui.control.Label
        AntennaheightmSlider        matlab.ui.control.Slider
        ModelDropDownLabel          matlab.ui.control.Label
        ModelDropDown               matlab.ui.control.DropDown
        StartEditFieldLabel         matlab.ui.control.Label
        StartEditField              matlab.ui.control.NumericEditField
        kmLabel                     matlab.ui.control.Label
        StopEditFieldLabel          matlab.ui.control.Label
        StopEditField               matlab.ui.control.NumericEditField
        kmLabel_2                   matlab.ui.control.Label
        BinsEditFieldLabel          matlab.ui.control.Label
        BinsEditField               matlab.ui.control.NumericEditField
        FreqEditFieldLabel          matlab.ui.control.Label
        FreqEditField               matlab.ui.control.NumericEditField
        MHzLabel                    matlab.ui.control.Label
        ButtonGroup                 matlab.ui.container.ButtonGroup
        RedrawButton                matlab.ui.control.RadioButton
        AddButton                   matlab.ui.control.RadioButton
        ReceiverheightmSliderLabel  matlab.ui.control.Label
        ReceiverheightmSlider       matlab.ui.control.Slider
        RightPanel                  matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        antHeight = 50;
        recHeight = 1.2;
        freq = 868;
        start = 0.1;
        stop = 10;
        step = 0.1;
        model = 'Free-Space';
        redraw = true;
        first = true;
    end

    
    methods (Access = private)
        
        function readParams(app)
            app.antHeight = app.AntennaheightmSlider.Value;
            app.recHeight = app.ReceiverheightmSlider.Value;
            app.freq = app.FreqEditField.Value;
            app.start = app.StartEditField.Value;
            app.stop = app.StopEditField.Value;
            app.step = abs(app.stop - app.start)/app.BinsEditField.Value;
            app.model = app.ModelDropDown.Value;
            app.redraw = app.RedrawButton.Value;
        end
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.readParams();
            
            leg = sprintf("%s, Ant ht.=%.1f m, Rec ht.=%.1f m, f=%.1f MHz", app.model, app.antHeight, app.recHeight, app.freq);
            
            switch app.model
                case 'Free-Space'
                    PL = free_space(app.freq, app.start, app.stop, app.step);
                    leg = sprintf("%s, f=%.1f MHz", app.model, app.freq);
                case 'Okumura-Hata Urban'
                   PL = hata_urban(app.antHeight, app.recHeight, app.freq, app.start, app.stop, app.step);
                case 'Okumura Hata Rural'
                    PL = hata_rural(app.antHeight, app.recHeight, app.freq, app.start, app.stop, app.step);
                case '3GPP Urban'
                    PL = urban_3gpp(app.antHeight, app.freq, app.start, app.stop, app.step);
                    leg = sprintf("%s, Ant ht.=%.1f m, f=%.1f MHz", app.model, app.antHeight, app.freq);
                case 'ECC33 Big City'
                    PL = ecc33(app.antHeight, app.recHeight, app.freq, app.start, app.stop, app.step, true);
                case 'ECC33 Medium City'
                    PL = ecc33(app.antHeight, app.recHeight, app.freq, app.start, app.stop, app.step, false);
                case 'Ericsson Urban'
                    PL = ericsson(app.antHeight, app.recHeight, app.freq, app.start, app.stop, app.step, 3);
                case 'Ericsson Suburban'
                    PL = ericsson(app.antHeight, app.recHeight, app.freq, app.start, app.stop, app.step, 2);
                case 'Ericsson Rural'
                    PL = ericsson(app.antHeight, app.recHeight, app.freq, app.start, app.stop, app.step, 1);
                case 'COST231  Large City'
                    PL = cost231(app.antHeight, app.recHeight, app.freq, app.start, app.stop, app.step, 3);
                case 'COST231  Medium City'
                    PL = cost231(app.antHeight, app.recHeight, app.freq, app.start, app.stop, app.step, 2);
                case 'COST231  Small City'
                    PL = cost231(app.antHeight, app.recHeight, app.freq, app.start, app.stop, app.step, 1);
                otherwise
                   PL = free_space(app.freq, app.start, app.stop, app.step);
            end
            if (app.redraw)
                hold(app.UIAxes, 'off');
            else
                hold(app.UIAxes, 'on');
            end
            
            if(app.first == true)
                app.first = false;
                legend(app.UIAxes,'Location','southeast');
            end
            
            plot(app.UIAxes, PL(1,:), PL(2,:), 'DisplayName', leg, 'LineWidth', 1.2);
            
        end

        % Value changed function: ModelDropDown
        function ModelDropDownValueChanged(app, event)
            value = app.ModelDropDown.Value;
            app.AntennaheightmSlider.Limits = [10, 110];
            app.AntennaheightmSlider.MajorTicks = [10,20,30,40,50,60,70,80,90,100,110];
            app.ReceiverheightmSlider.Enable = true;
            app.AntennaheightmSlider.Enable = true;
            switch value
                case 'Free-Space'
                    app.FreqEditField.Limits = [20, 100000];
                    app.ReceiverheightmSlider.Enable = false;
                    app.AntennaheightmSlider.Enable = false;
                case 'Okumura-Hata Urban'
                   app.FreqEditField.Limits = [150, 1500];
                case 'Okumura Hata Rural'
                   app.FreqEditField.Limits = [150, 1500];
                case '3GPP Urban'
                   app.ReceiverheightmSlider.Enable = false;
                   app.AntennaheightmSlider.Limits = [0, 50];
                   app.AntennaheightmSlider.MajorTicks = [0,5,10,15,20,25,30,35,40,45,50];
                   app.FreqEditField.Limits = [700, 2600];
                case 'ECC33 Big City'
                   app.FreqEditField.Limits = [700, 2600];
                case 'ECC33 Medium City'
                   app.FreqEditField.Limits = [700, 3500];
                case 'Ericsson Urban'
                   app.FreqEditField.Limits = [150, 1900];
                case 'Ericsson Suburban'
                    app.FreqEditField.Limits = [150, 1900];
                case 'Ericsson Rural'
                    app.FreqEditField.Limits = [150, 1900];
                case 'COST231  Large City'
                    app.FreqEditField.Limits = [150, 2000];
                case 'COST231  Medium City'
                    app.FreqEditField.Limits = [150, 2000];
                case 'COST231  Small City'
                    app.FreqEditField.Limits = [150, 2000];
                otherwise
                   
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.PropagationmodelsUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {783, 783};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {282, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PropagationmodelsUIFigure = uifigure('Visible', 'off');
            app.PropagationmodelsUIFigure.Name = 'Propagation models';
            app.PropagationmodelsUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.PropagationmodelsUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CalculateButton = uibutton(app.LeftPanel, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Tag = 'runBtn';
            app.CalculateButton.Text = 'Calculate';
            app.AntennaheightmSliderLabel = uilabel(app.LeftPanel);
            app.AntennaheightmSliderLabel.Text = 'Antenna height [m]';
            app.AntennaheightmSlider = uislider(app.LeftPanel);
            app.AntennaheightmSlider.Limits = [10 110];
            app.AntennaheightmSlider.MajorTicks = [10 20 30 40 50 60 70 80 90 100 110];
            app.AntennaheightmSlider.Tag = 'antHeightSlider';
            app.AntennaheightmSlider.Enable = 'off';
            app.AntennaheightmSlider.Value = 10;
            app.ModelDropDownLabel = uilabel(app.LeftPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.LeftPanel);
            app.ModelDropDown.Items = {'Free-Space', 'Okumura-Hata Urban', 'Okumura Hata Rural', '3GPP Urban', 'ECC33 Big City', 'ECC33 Medium City', 'Ericsson Urban', 'Ericsson Suburban', 'Ericsson Rural', 'COST231  Large City', 'COST231  Medium City', 'COST231  Small City'};
            app.ModelDropDown.ValueChangedFcn = createCallbackFcn(app, @ModelDropDownValueChanged, true);
            app.ModelDropDown.Tag = 'modelCombo';
            app.ModelDropDown.Value = 'Free-Space';
            app.StartEditFieldLabel = uilabel(app.LeftPanel);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StartEditField.Limits = [0 200];
            app.StartEditField.Tag = 'startNum';
            app.StartEditField.Value = 0.1;
            app.kmLabel = uilabel(app.LeftPanel);
            app.kmLabel.Text = 'km';
            app.StopEditFieldLabel = uilabel(app.LeftPanel);
            app.StopEditFieldLabel.Text = 'Stop';
            app.StopEditField = uieditfield(app.LeftPanel, 'numeric');
            app.StopEditField.Limits = [0 200];
            app.StopEditField.Tag = 'stopNum';
            app.StopEditField.Value = 10;
            app.kmLabel_2 = uilabel(app.LeftPanel);
            app.kmLabel_2.Text = 'km';
            app.BinsEditFieldLabel = uilabel(app.LeftPanel);
            app.BinsEditFieldLabel.Text = 'Bins';
            app.BinsEditField = uieditfield(app.LeftPanel, 'numeric');
            app.BinsEditField.Limits = [1 Inf];
            app.BinsEditField.Tag = 'binsNum';
            app.BinsEditField.Value = 100;
            app.FreqEditFieldLabel = uilabel(app.LeftPanel);
            app.FreqEditFieldLabel.Text = 'Freq.';
            app.FreqEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FreqEditField.Limits = [100 2000];
            app.FreqEditField.Tag = 'freqNum';
            app.FreqEditField.Value = 868;
            app.MHzLabel = uilabel(app.LeftPanel);
            app.MHzLabel.Text = 'MHz';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.ButtonGroup.Tag = 'redraw';
            app.RedrawButton = uiradiobutton(app.ButtonGroup);
            app.RedrawButton.Text = 'Redraw';
            app.RedrawButton.Value = true;
            app.AddButton = uiradiobutton(app.ButtonGroup);
            app.AddButton.Text = 'Add';
            app.ReceiverheightmSliderLabel = uilabel(app.LeftPanel);
            app.ReceiverheightmSliderLabel.Text = 'Receiver height [m]';
            app.ReceiverheightmSlider = uislider(app.LeftPanel);
            app.ReceiverheightmSlider.Limits = [1 15];
            app.ReceiverheightmSlider.Tag = 'rxHeightSlider';
            app.ReceiverheightmSlider.Enable = 'off';
            app.ReceiverheightmSlider.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '\it{Distance [km]}')
            ylabel(app.UIAxes, '\it{Pathloss [dB]}')
            app.UIAxes.Tag = 'pathFig';
            % Show the figure after all components are created
            app.PropagationmodelsUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = models

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PropagationmodelsUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PropagationmodelsUIFigure)
        end
    end
end